classdef simMapGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimulationMapGeneratorUIFigure  matlab.ui.Figure
        LineFollowingMapPanel           matlab.ui.container.Panel
        ThresholdModeButtonGroup        matlab.ui.container.ButtonGroup
        AutomaticButton                 matlab.ui.control.RadioButton
        ManualButton                    matlab.ui.control.RadioButton
        ThresholdLevelSliderLabel       matlab.ui.control.Label
        ThresholdLevelSlider            matlab.ui.control.Slider
        MapInitializationPanel          matlab.ui.container.Panel
        ImageFilenameEditFieldLabel     matlab.ui.control.Label
        ImageFilenameEditField          matlab.ui.control.EditField
        MapOptionsDropDownLabel         matlab.ui.control.Label
        MapOptionsDropDown              matlab.ui.control.DropDown
        LoadMapImageButton              matlab.ui.control.Button
        TrackSizeXYmEditFieldLabel      matlab.ui.control.Label
        TrackSizeXYmEditField           matlab.ui.control.EditField
        ObstacleMapPanel                matlab.ui.container.Panel
        NumberofObstaclesEditFieldLabel  matlab.ui.control.Label
        NumberofObstaclesEditField      matlab.ui.control.NumericEditField
        PlaceObstaclesButton            matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        ExportMapButton                 matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
    end

% Copyright 2017 The MathWorks, Inc.

    properties (Access = private)
        img; % Image data
        lineFollowingMap; % Map used for line following simulation
        obsPosition = []; % Location of obstacles placed
        obsMap; % Map used for obstacle detection/avoidance
        simMap; % Binary occupancy grid for the simulator
        scaleFactor;       
    end

    methods (Access = private)
    
        function thresholdMap(app)
            %% Get image
            imgRaw = app.img;
            
            %% Convert to grayscale
            imgGray = rgb2gray(imgRaw);
            
            %% Threshold for black lines
            selectedButton = app.ThresholdModeButtonGroup.SelectedObject;
                        
            if (strcmp(selectedButton.Text,'Automatic')) %Use grayThresh level if 'Automatic'
                level = graythresh(imgGray);
                BWMap = im2bw(imgGray,level);
                app.ThresholdLevelSlider.Value = level*255;
            else %Use manual level if 'Manual'
                manLevel = app.ThresholdLevelSlider.Value;
                level = (manLevel)/255; %Normalize between 0 and 1
                BWMap = im2bw(imgGray,level);
            end
            
                      
            %% Create map
            app.lineFollowingMap = single(BWMap);
                                             
        end
        
        function placeObjects(app)
            
            %% Get obstacle positions
            numObs = app.NumberofObstaclesEditField.Value;
            
            placeFig = figure('Name','Place Obstacles','NumberTitle','off');
            orig_state = warning;
            warning off
            imshow(app.img);
            warning(orig_state)
            placeAxes = gca;
            
            try
                
                % Draw rectangles
                for idx = 1:numObs
                    titleStr = ['Draw a rectangle over the obstacle area and double click inside it to place obstacle. Selecting ' num2str(idx) '/' num2str(numObs) ' obstacles'];
                    title(placeAxes,titleStr);
                    h = imrect(placeAxes);
                    app.obsPosition(idx,:) = wait(h);            
                end
                close(placeFig)
                
            catch
                
                numObsSpec = app.NumberofObstaclesEditField.Value;
                numObsAct = size(app.obsPosition,1);
                
                if isempty(numObsAct)
                
                    numObsAct = 0;
                    
                end
                
                if (numObsSpec ~= numObsAct)
                    
                    warnstr = ['Number of obstacles expected is ' num2str(numObsSpec)...
                        '. Number of obstacles actually placed is ' num2str(numObsAct) '.'];
                    uialert(app.SimulationMapGeneratorUIFigure,warnstr,'Warning','Icon','warning');
                
                end
            end
            
        end
        
        function createObsMap(app)
            
            %% Get obstacle positions
            numObs = app.NumberofObstaclesEditField.Value;
            obsLoc = app.obsPosition;
            
            imgSize = size(app.img);
            % trackSize = str2num(get_param(gcb,'trackSize'));
            
            % Initialize obstacle map
            locObsMap = ones(imgSize(1),imgSize(2),'single');
            
            if ~isempty(obsLoc)
            
                % Fill maps
                for idx = 1:size(obsLoc,1)
                    
                    % Get obstacle indices
                    currObsLoc = ceil(obsLoc(idx,:));
                    colIdx =  currObsLoc(1):(currObsLoc(1)+currObsLoc(3));
                    rowIdx =  currObsLoc(2):(currObsLoc(2)+currObsLoc(4));
                    
                    % Set obstacle indices to 0 in map
                    locObsMap(rowIdx,colIdx) = 0;    
                   
                end
            end
            
            app.obsMap = locObsMap;
            % scaleFactor = imgSize(1)/trackSize(1);
            
        end
        
        function updatePreview(app)
            
            value = app.MapOptionsDropDown.Value;
            
            if strcmp(value,app.MapOptionsDropDown.Items(1))
                
                % 'Line Following' is chosen
                previewMap = app.lineFollowingMap;
                                
            elseif strcmp(value,app.MapOptionsDropDown.Items(2))
                
                % 'Obstacle' is chosen
                previewMap = app.obsMap;
                
            elseif strcmp(value,app.MapOptionsDropDown.Items(3))
                
                % 'Both' is chosen
                previewMap = min(app.lineFollowingMap,app.obsMap);
                
            else
                
                % No option chosen yet. Display raw image
                previewMap = app.img;
                
            end
            
            imshow(previewMap,'Parent',app.UIAxes)
            drawnow; 
            app.simMap = previewMap;
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startUpApp(app)
            app.UIAxes.Visible = 'off';
        end

        % Close request function: SimulationMapGeneratorUIFigure
        function SimulationMapGeneratorUIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: ExportMapButton
        function exportMapSelect(app, event)
            
            [FileName,PathName] = uiputfile({'*.mat','MAT Files'},'Export Map Filename');
            
            value = app.MapOptionsDropDown.Value;
            
            if strcmp(value,app.MapOptionsDropDown.Items(1))
                
                % 'Line Following' is chosen
                mapForSim.lineFollowingMap = app.lineFollowingMap;
                mapForSim.obsMap = [];
                
            elseif strcmp(value,app.MapOptionsDropDown.Items(2))
                
                % 'Obstacle' is chosen
                mapForSim.lineFollowingMap = [];
                mapForSim.obsMap = app.obsMap;
                
            else
                
                % 'Both' is chosen
                mapForSim.lineFollowingMap = app.lineFollowingMap;
                mapForSim.obsMap = app.obsMap;
                
            end
            
            % Calculate scale factor
            
            trackSize = str2num(app.TrackSizeXYmEditField.Value);
            app.scaleFactor = size(app.simMap,2)/trackSize(1);
            mapForSim.scaleFactor = app.scaleFactor;
            
            % Create simulation map using binary occupancy grid
            compMap = imcomplement(app.simMap); %binary occupancy grid flips this for some reason
            app.simMap = robotics.BinaryOccupancyGrid(compMap,app.scaleFactor);
            mapForSim.simMap = app.simMap;
            
            % Save maps
            save([PathName FileName],'mapForSim');
                   
            uialert(app.SimulationMapGeneratorUIFigure,['Map exported successfully! Load ' FileName ' and use the mapForSim variable for simulation blocks.'],...
                'Export Status','Icon','success');
            
        end

        % Button pushed function: HelpButton
        function helpAppDoc(app, event)
            web('Simulation Map Generator Doc.htm')
        end

        % Button pushed function: LoadMapImageButton
        function loadMapImage(app, event)
            
            % Select file
            [FileName,PathName] = uigetfile({'*.jpg;*.tif;*.png;*.gif','All Image Files'},...
                'Pick Map Image');
            
            % Bring App into focus
            webWindows = matlab.internal.webwindowmanager.instance.windowList;
            
            for ii = 1:length(webWindows)
                
                webWindow = webWindows(ii);
                
                if strcmp( webWindow.Title, app.SimulationMapGeneratorUIFigure.Name )
                    
                    break
                    
                end
                
            end
            
            webWindow.setAlwaysOnTop(true);
            
            webWindow.setAlwaysOnTop(false);
               
                
            app.ImageFilenameEditField.Value = [PathName FileName];
            
            if(~isempty(app.ImageFilenameEditField.Value))
                 
                app.TrackSizeXYmEditField.Enable = 'on';
                app.TrackSizeXYmEditFieldLabel.Enable = 'on'; 
                
            else
                
                app.TrackSizeXYmEditField.Enable = 'off';
                app.TrackSizeXYmEditFieldLabel.Enable = 'off'; 
                               
            end
            
            % Read in image
            app.img = imread(app.ImageFilenameEditField.Value);
                       
            app.updatePreview;
        end

        % Value changed function: MapOptionsDropDown
        function mapOptionsSelect(app, event)
            value = app.MapOptionsDropDown.Value;
            
            if strcmp(value,app.MapOptionsDropDown.Items(1))
                
                % 'Line Following' is chosen
                % Switch on Line following options
                app.AutomaticButton.Enable = 'on';
                app.ManualButton.Enable = 'on';
                app.thresholdOptionsSelect;
                % Switch off Obstacle options
                app.NumberofObstaclesEditField.Enable = 'off';
                app.PlaceObstaclesButton.Enable = 'off';
                
                % Threshold line following map
                app.thresholdMap;
                
                % Switch on Export Map button
                app.ExportMapButton.Enable = 'on';
                
            elseif strcmp(value,app.MapOptionsDropDown.Items(2))
                
                % 'Obstacle' is chosen
                % Switch on Obstacle options
                app.NumberofObstaclesEditField.Enable = 'on';
                app.PlaceObstaclesButton.Enable = 'on';
                % Switch off Line following options
                app.AutomaticButton.Enable = 'off';
                app.ManualButton.Enable = 'off';
                app.ThresholdLevelSlider.Enable = 'off';
                app.ThresholdLevelSliderLabel.Enable = 'off';
                
                %Create obstacle map
                app.createObsMap;
                
                % Switch on Export Map button
                app.ExportMapButton.Enable = 'on';
                
            elseif strcmp(value,app.MapOptionsDropDown.Items(3))
                
                % 'Both' is chosen
                % Switch on Obstacle options
                app.NumberofObstaclesEditField.Enable = 'on';
                app.PlaceObstaclesButton.Enable = 'on';
                % Switch on Line following options
                app.AutomaticButton.Enable = 'on';
                app.ManualButton.Enable = 'on';
                                
                % Threshold line following map
                app.thresholdMap;
                %Create obstacle map
                app.createObsMap;
                
                % Switch on Export Map button
                app.ExportMapButton.Enable = 'on';
                
            else
                
                % No option is chosen
                % Switch off Obstacle options
                app.NumberofObstaclesEditField.Enable = 'off';
                app.PlaceObstaclesButton.Enable = 'off';
                % Switch off Line following options
                app.AutomaticButton.Enable = 'off';
                app.ManualButton.Enable = 'off';
                app.ThresholdLevelSlider.Enable = 'off';
                % Switch off Export Map button
                app.ExportMapButton.Enable = 'off';
                
            end
            
            app.updatePreview;
            
        end

        % Button pushed function: PlaceObstaclesButton
        function placeObstaclesSelect(app, event)
            app.placeObjects;
            app.createObsMap;
            app.updatePreview;
        end

        % Value changed function: ThresholdLevelSlider
        function sliderSelect(app, event)
            app.thresholdMap;
            app.updatePreview;
        end

        % Selection changed function: ThresholdModeButtonGroup
        function thresholdOptionsSelect(app, event)
            
            selectedButton = app.ThresholdModeButtonGroup.SelectedObject;
            
            % If 'Automatic' is chosen, disable manual slider
            if(strcmp(selectedButton.Text,'Automatic'))
                app.ThresholdLevelSlider.Enable = 'off';
            else
                app.ThresholdLevelSlider.Enable = 'on';
                app.ThresholdLevelSliderLabel.Enable = 'on';
            end
            
            % Threshold map whenever options change
            app.thresholdMap;
            app.updatePreview;
        end

        % Value changed function: TrackSizeXYmEditField
        function trackSizeSelect(app, event)
            value = str2num(app.TrackSizeXYmEditField.Value);
            
            if(all(value > 0) && numel(value) == 2)
                 
                app.MapOptionsDropDown.Enable = 'on';
                app.MapOptionsDropDownLabel.Enable = 'on';
                
            else
                
                app.MapOptionsDropDown.Enable = 'off';
                app.MapOptionsDropDownLabel.Enable = 'off';
                uialert(app.SimulationMapGeneratorUIFigure,'Track Size [x y] can only contain two elements with positive values.',...
                'Error','Icon','error');
                
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimulationMapGeneratorUIFigure = uifigure;
            app.SimulationMapGeneratorUIFigure.Name = 'Simulation Map Generator';
            app.SimulationMapGeneratorUIFigure.CloseRequestFcn = createCallbackFcn(app, @SimulationMapGeneratorUIFigureCloseRequest, true);
            app.LineFollowingMapPanel = uipanel(app.SimulationMapGeneratorUIFigure);
            app.LineFollowingMapPanel.Title = 'Line Following Map';
            app.ThresholdModeButtonGroup = uibuttongroup(app.LineFollowingMapPanel);
            app.ThresholdModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @thresholdOptionsSelect, true);
            app.ThresholdModeButtonGroup.Title = 'Threshold Mode';
            app.AutomaticButton = uiradiobutton(app.ThresholdModeButtonGroup);
            app.AutomaticButton.Enable = 'off';
            app.AutomaticButton.Text = 'Automatic';
            app.AutomaticButton.Value = true;
            app.ManualButton = uiradiobutton(app.ThresholdModeButtonGroup);
            app.ManualButton.Enable = 'off';
            app.ManualButton.Text = 'Manual';
            app.ThresholdLevelSliderLabel = uilabel(app.ThresholdModeButtonGroup);
            app.ThresholdLevelSliderLabel.Enable = 'off';
            app.ThresholdLevelSliderLabel.Text = 'Threshold Level';
            app.ThresholdLevelSlider = uislider(app.ThresholdModeButtonGroup);
            app.ThresholdLevelSlider.Limits = [0 255];
            app.ThresholdLevelSlider.MajorTicks = [0 50 100 150 200 255];
            app.ThresholdLevelSlider.ValueChangedFcn = createCallbackFcn(app, @sliderSelect, true);
            app.ThresholdLevelSlider.Enable = 'off';
            app.MapInitializationPanel = uipanel(app.SimulationMapGeneratorUIFigure);
            app.MapInitializationPanel.Title = 'Map Initialization';
            app.ImageFilenameEditFieldLabel = uilabel(app.MapInitializationPanel);
            app.ImageFilenameEditFieldLabel.Text = 'Image Filename';
            app.ImageFilenameEditField = uieditfield(app.MapInitializationPanel, 'text');
            app.MapOptionsDropDownLabel = uilabel(app.MapInitializationPanel);
            app.MapOptionsDropDownLabel.Enable = 'off';
            app.MapOptionsDropDownLabel.Text = 'Map Options';
            app.MapOptionsDropDown = uidropdown(app.MapInitializationPanel);
            app.MapOptionsDropDown.Items = {'Line Following', 'Obstacle', 'Both', 'Choose option'};
            app.MapOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @mapOptionsSelect, true);
            app.MapOptionsDropDown.Enable = 'off';
            app.MapOptionsDropDown.Value = 'Choose option';
            app.LoadMapImageButton = uibutton(app.MapInitializationPanel, 'push');
            app.LoadMapImageButton.ButtonPushedFcn = createCallbackFcn(app, @loadMapImage, true);
            app.LoadMapImageButton.Text = 'Load Map Image';
            app.TrackSizeXYmEditFieldLabel = uilabel(app.MapInitializationPanel);
            app.TrackSizeXYmEditFieldLabel.Enable = 'off';
            app.TrackSizeXYmEditFieldLabel.Text = 'Track Size [X Y] (m)';
            app.TrackSizeXYmEditField = uieditfield(app.MapInitializationPanel, 'text');
            app.TrackSizeXYmEditField.ValueChangedFcn = createCallbackFcn(app, @trackSizeSelect, true);
            app.TrackSizeXYmEditField.Enable = 'off';
            app.TrackSizeXYmEditField.Value = '[0 0]';
            app.ObstacleMapPanel = uipanel(app.SimulationMapGeneratorUIFigure);
            app.ObstacleMapPanel.Title = 'Obstacle Map';
            app.NumberofObstaclesEditFieldLabel = uilabel(app.ObstacleMapPanel);
            app.NumberofObstaclesEditFieldLabel.Text = 'Number of Obstacles';
            app.NumberofObstaclesEditField = uieditfield(app.ObstacleMapPanel, 'numeric');
            app.NumberofObstaclesEditField.Enable = 'off';
            app.NumberofObstaclesEditField.Value = 2;
            app.PlaceObstaclesButton = uibutton(app.ObstacleMapPanel, 'push');
            app.PlaceObstaclesButton.ButtonPushedFcn = createCallbackFcn(app, @placeObstaclesSelect, true);
            app.PlaceObstaclesButton.Enable = 'off';
            app.PlaceObstaclesButton.Text = 'Place Obstacles';
            app.UIAxes = uiaxes(app.SimulationMapGeneratorUIFigure);
            title(app.UIAxes, 'Map Preview')
            app.ExportMapButton = uibutton(app.SimulationMapGeneratorUIFigure, 'push');
            app.ExportMapButton.ButtonPushedFcn = createCallbackFcn(app, @exportMapSelect, true);
            app.ExportMapButton.Enable = 'off';
            app.ExportMapButton.Text = 'Export Map';
            app.HelpButton = uibutton(app.SimulationMapGeneratorUIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @helpAppDoc, true);
            app.HelpButton.Text = 'Help';
        end
    end

    methods (Access = public)

        % Construct app
        function app = simMapGenerator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimulationMapGeneratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startUpApp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimulationMapGeneratorUIFigure)
        end
    end
end